classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        nirs2nifti               matlab.ui.Figure
        BrowseButton             matlab.ui.control.Button
        BrowseButton_2           matlab.ui.control.Button
        SPMEditFieldLabel        matlab.ui.control.Label
        SPMEditField             matlab.ui.control.EditField
        ContrastEditFieldLabel   matlab.ui.control.Label
        ContrastEditField        matlab.ui.control.EditField
        DepthEditFieldLabel      matlab.ui.control.Label
        DepthEditField           matlab.ui.control.NumericEditField
        RadiusEditFieldLabel     matlab.ui.control.Label
        RadiusEditField          matlab.ui.control.NumericEditField
        TypeDropDownLabel        matlab.ui.control.Label
        TypeDropDown             matlab.ui.control.DropDown
        ResolutionDropDownLabel  matlab.ui.control.Label
        ResolutionDropDown       matlab.ui.control.DropDown
        StatusTextAreaLabel      matlab.ui.control.Label
        StatusTextArea           matlab.ui.control.TextArea
        RunButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        spm
        betas
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusTextArea.Value = "Welcome! ";
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [spm_file, spm_path] = uigetfile('*.mat');
            cd(spm_path)
            app.SPMEditField.Value = spm_file;
            app.spm = strcat(spm_path, spm_file);
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [betas_file, betas_path] = uigetfile('*.mat');
            app.ContrastEditField.Value = betas_file;
            app.betas = strcat(betas_path, betas_file);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            type = app.TypeDropDown.Value;
            radius = app.RadiusEditField.Value;
            depth = app.DepthEditField.Value;
            std = app.ResolutionDropDown.Value;
            if(isempty(app.spm))
                msg = "Please load SPM. ";
            elseif(isempty(app.betas))
                msg = "Please load Contrast. ";
            else
                path = nirs2nii(type, radius, depth, app.spm, app.betas, std);
                msg = sprintf("Done! NIfTI files generated in %s. ", path);
            end
            app.StatusTextArea.Value = strcat(app.StatusTextArea.Value, msg);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.nirs2nifti = uifigure;
            app.nirs2nifti.Name = 'UI Figure';
            app.BrowseButton = uibutton(app.nirs2nifti, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.nirs2nifti, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.SPMEditFieldLabel = uilabel(app.nirs2nifti);
            app.SPMEditFieldLabel.Text = 'SPM';
            app.SPMEditField = uieditfield(app.nirs2nifti, 'text');
            app.SPMEditField.Editable = 'off';
            app.ContrastEditFieldLabel = uilabel(app.nirs2nifti);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.nirs2nifti, 'text');
            app.ContrastEditField.Editable = 'off';
            app.DepthEditFieldLabel = uilabel(app.nirs2nifti);
            app.DepthEditFieldLabel.Text = 'Depth';
            app.DepthEditField = uieditfield(app.nirs2nifti, 'numeric');
            app.DepthEditField.Limits = [0 100];
            app.DepthEditField.Value = 25;
            app.RadiusEditFieldLabel = uilabel(app.nirs2nifti);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.nirs2nifti, 'numeric');
            app.RadiusEditField.Limits = [0 50];
            app.RadiusEditField.Value = 10;
            app.TypeDropDownLabel = uilabel(app.nirs2nifti);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.nirs2nifti);
            app.TypeDropDown.Items = {'Channels', 'Sources', 'Detectors', 'All'};
            app.TypeDropDown.ItemsData = {'c', 's', 'd', 'a'};
            app.TypeDropDown.Value = 'c';
            app.ResolutionDropDownLabel = uilabel(app.nirs2nifti);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.nirs2nifti);
            app.ResolutionDropDown.Items = {'2mm', '1mm', '0.5mm'};
            app.ResolutionDropDown.Value = '2mm';
            app.StatusTextAreaLabel = uilabel(app.nirs2nifti);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.nirs2nifti);
            app.StatusTextArea.Editable = 'off';
            app.RunButton = uibutton(app.nirs2nifti, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.nirs2nifti)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.nirs2nifti)
        end
    end
end