classdef nirs2nii < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main                     matlab.ui.Figure
        BrowseButton             matlab.ui.control.Button
        BrowseButton_2           matlab.ui.control.Button
        SPMEditFieldLabel        matlab.ui.control.Label
        SPMEditField             matlab.ui.control.EditField
        ContrastEditFieldLabel   matlab.ui.control.Label
        ContrastEditField        matlab.ui.control.EditField
        DepthmmEditFieldLabel    matlab.ui.control.Label
        DepthmmEditField         matlab.ui.control.NumericEditField
        RadiusmmEditFieldLabel   matlab.ui.control.Label
        RadiusmmEditField        matlab.ui.control.NumericEditField
        ResolutionDropDownLabel  matlab.ui.control.Label
        ResolutionDropDown       matlab.ui.control.DropDown
        RunButton                matlab.ui.control.Button
        StatusListBoxLabel       matlab.ui.control.Label
        StatusListBox            matlab.ui.control.ListBox
        NIfTIfileEditFieldLabel  matlab.ui.control.Label
        NIfTIfileEditField       matlab.ui.control.EditField
        BrowseButton_3           matlab.ui.control.Button
    end

    
    properties (Access = private)
        spm
        spm_dir
        betas
        raw
        raw_dir
        raw_file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [spm_file, spm_path] = uigetfile('*.mat');
            drawnow;
            figure(app.main);
            app.SPMEditField.Value = spm_file;
            app.spm_dir = spm_path;
            cd(spm_path)
            app.spm = strcat(spm_path, spm_file);
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [betas_file, betas_path] = uigetfile('*.mat');
            drawnow;
            figure(app.main);
            app.ContrastEditField.Value = betas_file;
            app.betas = strcat(betas_path, betas_file);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            radius = app.RadiusmmEditField.Value;
            depth = app.DepthmmEditField.Value;
            std = app.ResolutionDropDown.Value;
            
            if(isempty(app.spm))
                msg = "Please load SPM.";
            elseif(isempty(app.betas))
                msg = "Please load Contrast.";
            elseif(isempty(app.raw))
                msg = "Please load patient's anatomy .nii file.";
            else
                msg = sprintf('Genarating .nii file from nirsSPM results...');
                app.StatusListBox.Items = [app.StatusListBox.Items msg];
                [app_dir,~,~] = fileparts(mfilename('fullpath'));
                
                cd(app.raw_dir)
                results_dir = strcat('results_', datestr(now, 'yyyy-mm-dd-HHMMSS'));
                mkdir(results_dir)
                
                copyfile(strcat(app_dir, '\MNI152_T1_', std, '.nii'), results_dir);
                movefile(strcat(results_dir, '\MNI152_T1_', std, '.nii'), strcat(results_dir, '\icbm.nii'));
                
                load(app.spm); probes = spmData.nirsInfo.probeInfo.probes;
                load(app.betas); p_vals = data';
                make_nii(probes.coords_c3, probes.normals_c, p_vals, radius, depth, strcat(results_dir,'\channels.nii'), std)
                
                msg = sprintf('Transforming channels.nii into native space (~ 5 minutes)...');
                app.StatusListBox.Items = [app.StatusListBox.Items msg];
                
                run_jobs(app.raw_file, strcat(app_dir, '\TPM.nii'), strcat(results_dir,'\icbm.nii'), strcat(results_dir,'\channels.nii'))
                
                msg = sprintf("Success! NIfTI file (wchannels.nii) generated in %s.", results_dir);
                
                % handle files
                c1 = strcat('c1', app.raw_file);
                c2 = strcat('c2', app.raw_file);
                c3 = strcat('c3', app.raw_file);
                c4 = strcat('c4', app.raw_file);
                c5 = strcat('c5', app.raw_file);
                iy = strcat('iy_', app.raw_file);
                raw_seg8 = strcat(app.raw_file(1:length(app.raw_file)-4), '_seg8.mat');
                delete(c1, c2, c3, c4, c5, raw_seg8);
                movefile(iy,results_dir);                
            end
            app.StatusListBox.Items = [app.StatusListBox.Items msg];
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            [raw_filename, raw_path] = uigetfile('*.nii');
            drawnow;
            figure(app.main);
            app.NIfTIfileEditField.Value = raw_filename;
            app.raw_dir = raw_path;
            app.raw_file = raw_filename;
            app.raw = strcat(raw_filename, raw_path);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main = uifigure('Visible', 'off');
            app.main.Name = 'UI Figure';
            app.BrowseButton = uibutton(app.main, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'Select the nirsSPM results file which can be found in'; '  .. -> nirsSPM -> spm_data -> nirsSPM_NIRS...oxyHb.mat'};
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.main, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Tooltip = {'Select the p-values from the nirsSPM results'; 'Location: .. -> nirsSPM -> spm_data -> MAT -> nirsSPM_NIRS_......_pValues.mat'};
            app.BrowseButton_2.Text = 'Browse';
            app.SPMEditFieldLabel = uilabel(app.main);
            app.SPMEditFieldLabel.Text = 'SPM';
            app.SPMEditField = uieditfield(app.main, 'text');
            app.SPMEditField.Editable = 'off';
            app.SPMEditField.Tooltip = {'Select the nirsSPM results file which can be found in'; '  .. -> nirsSPM -> spm_data -> nirsSPM_NIRS...oxyHb.mat'; ''};
            app.ContrastEditFieldLabel = uilabel(app.main);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.main, 'text');
            app.ContrastEditField.Editable = 'off';
            app.ContrastEditField.Tooltip = {'Select the p-values from the nirsSPM results'; 'Location: .. -> nirsSPM -> spm_data -> MAT -> nirsSPM_NIRS_......_pValues.mat'};
            app.DepthmmEditFieldLabel = uilabel(app.main);
            app.DepthmmEditFieldLabel.Text = 'Depth (mm)';
            app.DepthmmEditField = uieditfield(app.main, 'numeric');
            app.DepthmmEditField.Limits = [0 100];
            app.DepthmmEditField.Tooltip = {'mm'};
            app.DepthmmEditField.Value = 25;
            app.RadiusmmEditFieldLabel = uilabel(app.main);
            app.RadiusmmEditFieldLabel.Text = 'Radius (mm)';
            app.RadiusmmEditField = uieditfield(app.main, 'numeric');
            app.RadiusmmEditField.Limits = [0 50];
            app.RadiusmmEditField.Tooltip = {'mm'};
            app.RadiusmmEditField.Value = 10;
            app.ResolutionDropDownLabel = uilabel(app.main);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.main);
            app.ResolutionDropDown.Items = {'2mm', '1mm', '0.5mm'};
            app.ResolutionDropDown.Value = '2mm';
            app.RunButton = uibutton(app.main, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StatusListBoxLabel = uilabel(app.main);
            app.StatusListBoxLabel.Text = 'Status';
            app.StatusListBox = uilistbox(app.main);
            app.StatusListBox.Items = {'Welcome!'};
            app.StatusListBox.Value = 'Welcome!';
            app.NIfTIfileEditFieldLabel = uilabel(app.main);
            app.NIfTIfileEditFieldLabel.Text = 'NIfTI file';
            app.NIfTIfileEditField = uieditfield(app.main, 'text');
            app.NIfTIfileEditField.Editable = 'off';
            app.NIfTIfileEditField.Tooltip = {'Select the NIfTI file of the patient''s anatomy (T1 raw .nii file) on which the channels with high p-values need to be highlighted'};
            app.BrowseButton_3 = uibutton(app.main, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Tooltip = {'Select the NIfTI file of the patient''s anatomy (T1 raw .nii file) on which the channels with high p-values need to be highlighted'};
            app.BrowseButton_3.Text = 'Browse';
            % Show the figure after all components are created
            app.main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nirs2nii

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main)
        end
    end
end